classdef progetto_matlab_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProgettoUIFigure             matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DatirealiTab                 matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        GraficiTab                   matlab.ui.container.Tab
        TabGroup3                    matlab.ui.container.TabGroup
        Grafico1Tab                  matlab.ui.container.Tab
        Grafico_1                    matlab.ui.control.UIAxes
        Grafico2Tab                  matlab.ui.container.Tab
        Grafico_2                    matlab.ui.control.UIAxes
        Grafico3Tab                  matlab.ui.container.Tab
        Grafico_3                    matlab.ui.control.UIAxes
        Grafico4Tab                  matlab.ui.container.Tab
        Grafico_4                    matlab.ui.control.UIAxes
        Grafico5Tab                  matlab.ui.container.Tab
        Grafico_5                    matlab.ui.control.UIAxes
        SelezionaDatidaVisualizareLabel  matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        TabelaRisultati              matlab.ui.control.Table
        TuttiiRisultatideiTestTab    matlab.ui.container.Tab
        TabelaTuttiTest              matlab.ui.control.Table
        EsportainLaTexButtonGroup    matlab.ui.container.ButtonGroup
        SiButton                     matlab.ui.control.RadioButton
        NoButton                     matlab.ui.control.RadioButton
        CaricaTabela                 matlab.ui.control.Button
        DatitestconsimulazioneTab    matlab.ui.container.Tab
        DatiacquisiticontelefonoTab  matlab.ui.container.Tab
    end


    % Conversione del progetto da GUI ad appdesigner
    % Dritan Progri 17/10/2017
    
    
    properties (Access = private)
        L_t  = [];% Description
        S_t = [] % Description
        Lu_t = [] % Description
    end
  
    methods (Access = private)
    
        
        function [N , R , psi , theta , phi, dati] = caricaDati(app,file)
            
            load(file);
            dati = RPY_Data*pi/180;
            N = size(dati,2);       % Numero di campioni acquisiti
            R=2500;                 %prime 2500 ierazioni da non considerare
            psi= dati(1,R:N);
            theta= dati(2,R:N);
            phi= dati(3,R:N);
            
        end
        
        
        function [Lxv,x,v,l,s,LurchIndex] = calcola(app,N,R,Psi,Theta,Phi)

            load('lib.mat');
            psi=unwrap(Psi);
            theta=unwrap(Theta);
            phi=unwrap(Phi);
            

            t=0.1;                  %Sempre uguale per tutti i test
            T=t*(N-R);              %tempo totale

            dpsi=diff(psi)/t;
            dtheta=diff(theta)/t;
            dphi=diff(phi)/t;

            ddpsi=diff(dpsi)/t;
            ddtheta=diff(dtheta)/t;
            ddphi=diff(dphi)/t;

            dddpsi=diff(ddpsi)/t;
            dddtheta=diff(ddtheta)/t;
            dddphi=diff(ddphi)/t;
            x=zeros(1,N-R-3);
            
            for i=1:N-R-3           %valori attesi di v dalla formula analitica
                 x(i)=jerk_analitico(psi(i),theta(i),phi(i),dpsi(i),dtheta(i),dphi(i),ddpsi(i),ddtheta(i),ddphi(i),dddpsi(i),dddtheta(i),dddphi(i));
   
            end
            X = sum(x);             

            i = length(dpsi);
            wx = zeros(1,i); wy = zeros(1,i); wz = zeros(1,i);   
            wx = dtheta.*sin(psi(1:i)) + dphi.*cos(psi(1:i)).*cos(theta(1:i));
            wy = dphi.*cos(theta(1:i)).*sin(psi(1:i)) - dtheta.*cos(psi(1:i));
            wz = dpsi + dphi.*sin(theta(1:i));
            W=[wx;wy;wz];           %velocità angolare in funzione degli angoli

            dwx= diff(wx)/t;
            dwy= diff(wy)/t;
            dwz= diff(wz)/t;
            dW=[dwx;dwy;dwz];

            ddwx= diff(dwx)/t; 
            ddwy= diff(dwy)/t;     
            ddwz= diff(dwz)/t;
            ddW=[ddwx;ddwy;ddwz];

        
            i=length(ddwx);
            Vx=zeros(1,i);
            Vy=zeros(1,i); 
            Vz=zeros(1,i); 
            
            i=1;
            while (i<N-2-R)           %perchè quando derivo perdo un elemento 
                a=[wx(i);wy(i);wz(i)];
                b=[dwx(i);dwy(i);dwz(i)];
                k=(cross(a,b))/2;
                Vx(i)=ddwx(i)+k(1,1);
                Vy(i)=ddwy(i)+k(2,1);
                Vz(i)=ddwz(i)+k(3,1);
                v(i)=norm([Vx(i);Vy(i);Vz(i)]);
                i=i+1;
            end 
            
            %Valorizzo l'integrando V            
            V=sum(v(1:(i-1)));
            l=V*t;
            s=0;
            i=1;
            
            while (i<N-2-R)
                m(i)=norm([wx(i);wy(i);wz(i)]);
                s=s+m(i);
                i=i+1;
            end
            
            Lxv = length(x);
            S=s*t;
            LurchIndex = T^2*l/S;
            
            %carico i valori finali
            app.L_t = [app.L_t l];
            app.S_t = [app.S_t s];
            app.Lu_t = [app.Lu_t LurchIndex];
            
            
        end
        
        function disegnaGrafici(app,Lxv,x,v,dati,N,R)
            
            FS = 15;
            Label = {'Yaw $\psi_k$ [rad]','Pitch $\theta_k$ [rad]','Roll $\phi_k$ [rad]','Val. Analitico'};
            titolo = app.ListBox.Value;
            plot(app.Grafico_1,1:Lxv,x,'b.',1:Lxv,v,'or');
            title(app.Grafico_1,titolo,'Interpreter','LaTeX','FontSize',11);
            xlabel(app.Grafico_1,'Indice temporale discreto $k$','Interpreter','LaTeX','FontSize',FS)
            ylabel(app.Grafico_1,'Valore del lurch all''indice $k$','Interpreter','LaTeX','FontSize',FS)
            legend(app.Grafico_1,'Valori ottenuti in maniera analitica','Valori ottenuti con approssimazioni numeriche')        
        
            lim=max([v x]);
            plot(app.Grafico_2,x,v,'k.',[0 lim],[0 lim],'b');
            title(app.Grafico_2,'Rapporto tra dati tratti in maniera analitica e in maniera numerica','Interpreter','LaTeX','FontSize',FS)
            xlabel (app.Grafico_2,'Valori ottenuti con formula analitica','Interpreter','LaTeX','FontSize',FS)
            ylabel (app.Grafico_2,'Valori ottenuti con approssimazioni numeriche','Interpreter','LaTeX','FontSize',FS)


            dati(1,:) = unwrap(dati(1,:));    
            plot(app.Grafico_3,1:N,dati(1,1:N),'LineWidth',2);
            title(app.Grafico_3,titolo,'Interpreter','LaTeX','FontSize',11)
            ylabel(app.Grafico_3,Label{1},'Interpreter','LaTeX','FontSize',FS)
            xlim(app.Grafico_3,[R N])           
            xlabel(app.Grafico_3,'Sample $k$','Interpreter','LaTeX','FontSize',FS)
        
            dati(2,:) = unwrap(dati(2,:));    
            plot(app.Grafico_4,1:N,dati(2,1:N),'LineWidth',2);
            title(app.Grafico_4,titolo,'Interpreter','LaTeX','FontSize',11)
            ylabel(app.Grafico_4,Label{2},'Interpreter','LaTeX','FontSize',FS)
            xlim(app.Grafico_4,[R N])           
            xlabel(app.Grafico_4,'Sample $k$','Interpreter','LaTeX','FontSize',FS)
        
            dati(2,:) = unwrap(dati(2,:));    
            plot(app.Grafico_5,1:N,dati(3,1:N),'LineWidth',2);
            title(app.Grafico_5,titolo,'Interpreter','LaTeX','FontSize',11)
            ylabel(app.Grafico_5,Label{3},'Interpreter','LaTeX','FontSize',FS)
            xlim(app.Grafico_5,[R N])           
            xlabel(app.Grafico_5,'Sample $k$','Interpreter','LaTeX','FontSize',FS)
       
        end
        
        
        function tuttiTest(app,file)
            n = length(file);
            for k = 1:n
                [N , R , psi , theta , phi, ~] = caricaDati(app,file{k});
                [~ ,~ ,~,~,~,~] = calcola(app,N , R , psi , theta , phi);
            end    
        end
        
        function salvaFile(app)
           
           FileName = uiputfile('.tex','Esporta in LaTex','tabella.tex');
           input.data = [app.L_t; app.S_t; app.Lu_t]'; 
           input.transposeTable = 0;
           input.dataFormatMode = 'column';
           input.dataFormat = {'%.3f',3};
           input.tableBorders = 1;
           % LaTex table caption:
           input.tableCaption = 'MyTableCaption';
           % LaTex table label:
           input.tableLabel = 'Tutti i risultati dei test';
           input.makeCompleteLatexDocument = 1;
           latex = latexTable(input);
           if FileName ~= 0
                fid=fopen(FileName,'wt+');
                [nrows,~] = size(latex);
                for row = 1:nrows
                    fprintf(fid,'%s\n',latex{row,:});
                end
                fclose(fid);
           end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            file = app.ListBox.Value;
            [N , R , psi , theta , phi,dati] = caricaDati(app,file);
            [Lxv,x,v,l,s,LurchIndex] = calcola(app,N , R , psi , theta , phi);
            disegnaGrafici(app,Lxv,x,v,dati,N,R);
            pause(1);                            %serve la pausa se no, non je la fa... :D
            app.TabelaRisultati.Data = num2cell([l s LurchIndex]);
            
        end

        % Button pushed function: CaricaTabela
        function CaricaTabelaButtonPushed(app, event)
             
               app.L_t = [];
               app.S_t = [];
               app.Lu_t = [];
               file = app.ListBox.ItemsData;
               app.TabelaTuttiTest.RowName = file;
               tuttiTest(app,file);
               dati = [num2cell(app.L_t') num2cell(app.S_t') num2cell(app.Lu_t')];
               app.TabelaTuttiTest.Data = dati;
               if app.SiButton.Value == 1
                    pause(1);
                    salvaFile(app);
               end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProgettoUIFigure = uifigure;
            app.ProgettoUIFigure.Name = 'Progetto';
            app.TabGroup = uitabgroup(app.ProgettoUIFigure);
            app.DatirealiTab = uitab(app.TabGroup);
            app.DatirealiTab.Title = 'Dati reali';
            app.TabGroup2 = uitabgroup(app.DatirealiTab);
            app.GraficiTab = uitab(app.TabGroup2);
            app.GraficiTab.Title = 'Grafici';
            app.TabGroup3 = uitabgroup(app.GraficiTab);
            app.Grafico1Tab = uitab(app.TabGroup3);
            app.Grafico1Tab.Title = 'Grafico 1';
            app.Grafico_1 = uiaxes(app.Grafico1Tab);
            xlabel(app.Grafico_1, 'Asse X')
            ylabel(app.Grafico_1, 'Asse Y')
            app.Grafico2Tab = uitab(app.TabGroup3);
            app.Grafico2Tab.Title = 'Grafico 2';
            app.Grafico_2 = uiaxes(app.Grafico2Tab);
            xlabel(app.Grafico_2, 'Asse X')
            ylabel(app.Grafico_2, 'Asse Y')
            app.Grafico3Tab = uitab(app.TabGroup3);
            app.Grafico3Tab.Title = 'Grafico 3';
            app.Grafico_3 = uiaxes(app.Grafico3Tab);
            xlabel(app.Grafico_3, 'Asse X')
            ylabel(app.Grafico_3, 'Asse Y')
            app.Grafico4Tab = uitab(app.TabGroup3);
            app.Grafico4Tab.Title = 'Grafico 4';
            app.Grafico_4 = uiaxes(app.Grafico4Tab);
            xlabel(app.Grafico_4, 'Asse X')
            ylabel(app.Grafico_4, 'Asse Y')
            app.Grafico5Tab = uitab(app.TabGroup3);
            app.Grafico5Tab.Title = 'Grafico 5';
            app.Grafico_5 = uiaxes(app.Grafico5Tab);
            xlabel(app.Grafico_5, 'Asse X')
            ylabel(app.Grafico_5, 'Asse Y')
            app.SelezionaDatidaVisualizareLabel = uilabel(app.GraficiTab);
            app.SelezionaDatidaVisualizareLabel.Text = 'Seleziona Dati da Visualizare';
            app.ListBox = uilistbox(app.GraficiTab);
            app.ListBox.Items = {'Non Scattoso v1 01dic15', 'Non Scattoso v2 01dic15', 'Scattoso v1 01dic15', 'Scattoso v2 01dic15', 'Test5 10dic15cle', 'Test6 10dic15', 'Test7 10dic15', 'Test8 10dic15', 'Test9 10dic15', 'Test10 10dic15'};
            app.ListBox.ItemsData = {'NScattoso_v1_01dic15.mat', 'NScattoso_v2_01dic15.mat', 'Scattoso_v1_01dic15.mat', 'Scattoso_v2_01dic15.mat', 'a1_10dic15.mat', 'a2_10dic15.mat', 'b1_10dic15.mat', 'b2_10dic15.mat', 'c1_10dic15.mat', 'c2_10dic15.mat'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TabelaRisultati = uitable(app.GraficiTab);
            app.TabelaRisultati.ColumnName = {'L'; 'S'; 'LurchIndex'};
            app.TabelaRisultati.RowName = {};
            app.TuttiiRisultatideiTestTab = uitab(app.TabGroup2);
            app.TuttiiRisultatideiTestTab.Title = 'Tutti i Risultati dei Test';
            app.TabelaTuttiTest = uitable(app.TuttiiRisultatideiTestTab);
            app.TabelaTuttiTest.ColumnName = {'L'; 'S'; 'LurchIndex'};
            app.TabelaTuttiTest.RowName = {};
            app.EsportainLaTexButtonGroup = uibuttongroup(app.TuttiiRisultatideiTestTab);
            app.EsportainLaTexButtonGroup.Title = 'Esporta in LaTex';
            app.SiButton = uiradiobutton(app.EsportainLaTexButtonGroup);
            app.SiButton.Text = 'Si';
            app.NoButton = uiradiobutton(app.EsportainLaTexButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.CaricaTabela = uibutton(app.TuttiiRisultatideiTestTab, 'push');
            app.CaricaTabela.ButtonPushedFcn = createCallbackFcn(app, @CaricaTabelaButtonPushed, true);
            app.CaricaTabela.Text = 'Carica Risultati';
            app.DatitestconsimulazioneTab = uitab(app.TabGroup);
            app.DatitestconsimulazioneTab.Title = 'Dati test (con simulazione)';
            app.DatiacquisiticontelefonoTab = uitab(app.TabGroup);
            app.DatiacquisiticontelefonoTab.Title = 'Dati acquisiti con telefono';
        end
    end

    methods (Access = public)

        % Construct app
        function app = progetto_matlab_2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProgettoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProgettoUIFigure)
        end
    end
end